classdef app6BRO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab_Nghiem                     matlab.ui.container.Tab
        Nghiem_PhuongPhap              matlab.ui.control.DropDown
        PhngPhpDropDownLabel           matlab.ui.control.Label
        Nghiem_gxFunc                  matlab.ui.control.EditField
        gxEditFieldLabel               matlab.ui.control.Label
        Nghiem_slLap                   matlab.ui.control.EditField
        SLnLpLabel                     matlab.ui.control.Label
        Nghiem_KQua                    matlab.ui.control.EditField
        KTQULabel                      matlab.ui.control.Label
        Nghiem_epsilon                 matlab.ui.control.EditField
        epsilonLabel                   matlab.ui.control.Label
        Nghiem_B                       matlab.ui.control.EditField
        bEditFieldLabel                matlab.ui.control.Label
        Nghiem_A                       matlab.ui.control.EditField
        aEditFieldLabel                matlab.ui.control.Label
        Nghiem_Func                    matlab.ui.control.EditField
        fxEditFieldLabel               matlab.ui.control.Label
        Nghiem_ThcthiButton            matlab.ui.control.Button
        UIAxesNghiem                   matlab.ui.control.UIAxes
        Tab_NoiSuy                     matlab.ui.container.Tab
        ButtonNoisuy                   matlab.ui.control.Button
        Labelkq                        matlab.ui.control.Label
        EditFieldgtri                  matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel            matlab.ui.control.Label
        Labeldathuc                    matlab.ui.control.Label
        DropDownNoiSuy                 matlab.ui.control.DropDown
        ChnphngphpnisuyLabel           matlab.ui.control.Label
        EditFieldy                     matlab.ui.control.EditField
        yLabel                         matlab.ui.control.Label
        EditFieldx                     matlab.ui.control.EditField
        xLabel                         matlab.ui.control.Label
        UIAxesNoiSuy                   matlab.ui.control.UIAxes
        Tab_HoiQuy                     matlab.ui.container.Tab
        NhapGiaTriCanDuDoanEditField   matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        ChonPhuongPhapHoiQuyDropDown   matlab.ui.control.DropDown
        ChnPhngPhpHiQuyDropDownLabel   matlab.ui.control.Label
        HoiQuy_NhapDuLieuYEditField    matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        HoiQuy_NhapDuLieuXEditField    matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        HoiQuy_TINHButton              matlab.ui.control.Button
        HoiQuy_KetQuaDuDoanLabel       matlab.ui.control.Label
        HoiQuy_KetQuaPhuongTrinhHoiQuyLabel  matlab.ui.control.Label
        UIAxesHoiQuy                   matlab.ui.control.UIAxes
        Tab_DaoHam                     matlab.ui.container.Tab
        KQLabel_2                      matlab.ui.control.Label
        NhphmsEditField                matlab.ui.control.EditField
        NhphmsEditFieldLabel_2         matlab.ui.control.Label
        NhpmngxEditField               matlab.ui.control.EditField
        NhpmngxEditFieldLabel          matlab.ui.control.Label
        ChonCachNhapButtonGroup        matlab.ui.container.ButtonGroup
        NhphmsButton                   matlab.ui.control.RadioButton
        NhpmngxyButton                 matlab.ui.control.RadioButton
        GiatrissDropDown               matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel       matlab.ui.control.Label
        NhpmngyEditField               matlab.ui.control.EditField
        NhpmngyEditFieldLabel          matlab.ui.control.Label
        ChonppDaoHam                   matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel     matlab.ui.control.Label
        KQLabel                        matlab.ui.control.Label
        DaoHamButton                   matlab.ui.control.Button
        Nhapgiatricantinh              matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        NhapbuochEditField             matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        Tab_TichPhan                   matlab.ui.container.Tab
        TichPhan_CachNhapDuLieu        matlab.ui.control.DropDown
        CchNhpDropDownLabel            matlab.ui.control.Label
        TichPhan_NhapN                 matlab.ui.control.NumericEditField
        NhpnLabel                      matlab.ui.control.Label
        CnbEditFieldLabel              matlab.ui.control.Label
        TichPhan_CanB                  matlab.ui.control.EditField
        TichPhan_CanA                  matlab.ui.control.EditField
        CnaEditFieldLabel              matlab.ui.control.Label
        TichPhan_Lamp                  matlab.ui.control.Lamp
        PhthinliLampLabel              matlab.ui.control.Label
        TichPhan_NhapY                 matlab.ui.control.EditField
        MngYLabel                      matlab.ui.control.Label
        TichPhan_NhapX                 matlab.ui.control.EditField
        MngXLabel                      matlab.ui.control.Label
        TichPhan_NhapHamSo             matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        TichPhan_KetQua                matlab.ui.control.Label
        TichPhan_Tinh                  matlab.ui.control.Button
        TichPhan_PhuongPhapTP          matlab.ui.container.ButtonGroup
        Simpson3phan8                  matlab.ui.control.RadioButton
        Simpson1phan3                  matlab.ui.control.RadioButton
        HinhThang                      matlab.ui.control.RadioButton
        TichPhan_Hoac                  matlab.ui.control.Label
        Tab_GioiThieuNhom              matlab.ui.container.Tab
        TRNLNHTQUIN21200342Label       matlab.ui.control.Label
        NGUYNTHAVHIP21200288Label      matlab.ui.control.Label
        TRNHUHNH21200286Label          matlab.ui.control.Label
        TRNNGCHUY20200040Label         matlab.ui.control.Label
        NGUYNGIANGThk21200284Label     matlab.ui.control.Label
        DANHCHHINNhmtrng21200287Label  matlab.ui.control.Label
        GINGVINHNGDNHUNHQUCTHNHLabel   matlab.ui.control.Label
        NHMSINHVINTHCHINNHM6BROLabel   matlab.ui.control.Label
        NCUIKPHNGPHPTNHVMATLABLabel    matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HoiQuy_TINHButton
        function HoiQuy_TINHButtonPushed(app, event)
            % Chuyển đổi giá trị nhập vào từ người dùng thành số
            x = str2num(app.HoiQuy_NhapDuLieuXEditField.Value);
            y = str2num(app.HoiQuy_NhapDuLieuYEditField.Value);
            x_pred = str2num(app.NhapGiaTriCanDuDoanEditField.Value);

            %Kiểm tra kích thước cửa ma trận x và y
            if length(x) ~= length(y)
                uialert(app.UIFigure, 'Kích thước của ma trận x và y phải bằng nhau!', "Lỗi!!!");
                return;
            end
            
            % Khởi tạo x_pos và yfit
            x_pos = x;
            yfit = zeros(size(x));
            
            % Lấy phương pháp hồi quy từ người dùng
            method = app.ChonPhuongPhapHoiQuyDropDown.Value;
            
            switch method
                case 'Tuyến tính'
                    % Hồi quy tuyến tính
                    [a1, a0, r2] = HoiQuy_Regress(x, y);
                    yfit = a0 + a1*x;
                    y_pred = a0 + a1*x_pred;
                    app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = sprintf('y = %.2f + %.2f*x, \t R^2 = %.2f', a0, a1, r2);
                    app.HoiQuy_TINHButton.BackgroundColor = [255 0 0]/255;
                
                case 'Hàm mũ'
                    % Hồi quy hàm mũ
                    X = [ones(length(x),1) x'];
                    B = X\log(y)';
                    a = exp(B(1));
                    b = B(2);
                    yfit = a*exp(b*x);
                    y_pred = a*exp(b*x_pred);
                    app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = sprintf('y = %.2f*exp(%.2f*x)', a, b);
                    app.HoiQuy_TINHButton.BackgroundColor = [0 255 0]/255;
                
                case 'Logarit'
                    % Hồi quy logarit

                    % Lọc ra các giá trị x dương
                    idx = x > 0;
                    x_pos = x(idx);
                    y_pos = y(idx);

                    % Nối các mảng cột
                    X = [ones(length(x_pos),1) log(x_pos)'];

                    % Tính B
                    B = X\y_pos';
                    a = B(1);
                    b = B(2);
                    yfit = a + b*log(x_pos);
                    y_pred = a + b*log(x_pred);
                    app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = sprintf('y = %.2f + %.2f*log(x)', a, b);
                    app.HoiQuy_TINHButton.BackgroundColor = [0 0 255]/255;
            end
            
            % Cập nhật Label với giá trị dự đoán
            app.HoiQuy_KetQuaDuDoanLabel.Text = sprintf('Giá trị dự đoán cho %.2f là %.2f', x_pred, y_pred);
            
            % Vẽ hàm hồi quy và dữ liệu lên đồ thị
            plot(app.UIAxesHoiQuy, x, y, 'ko', x_pos, yfit, 'b-');
            hold(app.UIAxesHoiQuy, 'on');
            plot(app.UIAxesHoiQuy, x_pred, y_pred, 'ro');
            hold(app.UIAxesHoiQuy, 'off');
            legend(app.UIAxesHoiQuy, 'Dữ liệu', sprintf('%s fit', method), 'Dự đoán', 'Location', 'best');
        end

        % Button pushed function: Nghiem_ThcthiButton
        function Nghiem_ThcthiButtonPushed(app, event)
            maxIter = 20; %Số lần lặp tối đa
            
            % Kiểm tra xem có ô nào rỗng không
            if isempty(app.Nghiem_PhuongPhap.Value)
                uialert(app.UIFigure, 'Chưa chọn phương pháp', 'Lỗi');
                return;
            end
            
            % Kiểm tra ô g(x) va f(x) có rỗng hoac không nhap dung dinh
            % dang hay khong?
            if strcmp(app.Nghiem_PhuongPhap.Value, 'Lặp')
                try
                    g = str2func(['@(x)', app.Nghiem_gxFunc.Value]);

                catch
                    uialert(app.UIFigure, 'Nhập sai định dạng (function g(x)) hoặc đang rỗng!', 'Lỗi');
                    return;
                end
            else
                try
                    f =  str2func(['@(x)', app.Nghiem_Func.Value]);

                catch
                    uialert(app.UIFigure, 'Nhập sai định dạng (function f(x)) hoặc đang rỗng!', 'Lỗi');
                    return;
                end
            end

            % Gán Các Value Từ Text Field Vào Các Variable
            %Kiem tra co nhap dung dinh dang f(x) khong

            % Kiem tra co nhap dung dinh dang a va b hoặc đang rỗng không
            try
                a = str2double(app.Nghiem_A.Value);
                if isnan(a)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (a (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end

            try
                b = str2double(app.Nghiem_B.Value);
                if isnan(b)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (b (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end
            
            % Kiểm tra có nhập đúng định dạng epsilon hoặc đang rỗng không?
            try
                epsilon = str2double(app.Nghiem_epsilon.Value);
                if isnan(epsilon)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (epsilon (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end

            % kiểm tra khoảng phân ly có hợp lệ hay không?
            if a >= b
                app.Nghiem_KQua.Value = 'NaN';
                app.Nghiem_slLap.Value = 'NaN';
                message = 'Khoảng phân ly không hợp lệ hoặc không tồn tại khoảng phân ly với các giá trị a và b';
                uialert(app.UIFigure, message, 'Lỗi');
                return;
            end

            % kiểm tra trong khoảng phân ly có nghiệm hay không?
            if strcmp(app.Nghiem_PhuongPhap.Value, 'Lặp')
%                 if sign(g(a)) == sign(g(b))
%                     app.Nghiem_KQua.Value = 'NaN';
%                     app.Nghiem_slLap.Value = 'NaN';
%                     message = 'Khoảng phân ly không chứa nghiệm hoặc chứa một số nghiệm chẵn. Vui lòng chọn khoảng phân ly khác.';
%                     uialert(app.UIFigure, message, 'Lỗi');
%                     return;
%                 end
            elseif sign(f(a)) == sign(f(b))
                app.Nghiem_KQua.Value = 'NaN';
                app.Nghiem_slLap.Value = 'NaN';
                message = 'Khoảng phân ly không chứa nghiệm hoặc chứa một số nghiệm chẵn. Vui lòng chọn khoảng phân ly khác.';
                uialert(app.UIFigure, message, 'Lỗi');
                return;
            end
    

            % Chọn hàm để function của phương pháp nào để giải phương trình
            switch app.Nghiem_PhuongPhap.Value
                case 'Chia đôi'
                    [root, iteration] = Nghiem_PhuongPhapChiaDoi(f, a, b, epsilon);

                case 'Lặp'
                    [root, iteration, message] = Nghiem_PhuongPhapLap(g, a, b, epsilon, maxIter);
                    if ~isempty(message)
                        uialert(app.UIFigure, message, 'Lỗi');
                        return;
                    end
                    

                case 'Newton'
                    [root, iteration] = Nghiem_PhuongPhapNewton(f, a, epsilon, maxIter);
            end

            app.Nghiem_KQua.Value = num2str(root);
            app.Nghiem_slLap.Value = num2str(iteration);

            % Vẽ đồ thị
            x = linspace(a/3, b*3, 1000);
            if strcmp(app.Nghiem_PhuongPhap.Value, 'Lặp')
                y = arrayfun(g, x);

            else
                y = arrayfun(f, x);
            end
            plot(app.UIAxesNghiem, x, y, 'b-', 'LineWidth', 1.5);
            grid(app.UIAxesNghiem, 'on');
        end

        % Value changed function: Nghiem_PhuongPhap
        function Nghiem_PhuongPhapValueChanged(app, event)
            selectedOption = app.Nghiem_PhuongPhap.Value;
        
            % Kiểm tra giá trị của dropdown và ẩn/hiển thị EditField dựa trên điều kiện
            if strcmp(selectedOption, 'Lặp') % Thay 'Option1' bằng giá trị của phần tử bạn muốn kiểm tra
                app.Nghiem_gxFunc.Visible = 'on'; % Ẩn EditField
                app.gxEditFieldLabel.Visible = 'on';

                app.Nghiem_Func.Enable = "off";

                uialert(app.UIFigure, "Người dùng phải tự tìm hàm lặp khi sử dụng phương pháp lặp!!!", "Lưu Ý");

                
            else
                app.Nghiem_gxFunc.Visible = 'off'; % Hiển thị EditField
                app.gxEditFieldLabel.Visible = 'off';

                app.Nghiem_Func.Enable = "on";
            end
        end

        % Button pushed function: TichPhan_Tinh
        function TichPhan_TinhButtonPushed(app, event)
            hamSo = app.TichPhan_NhapHamSo.Value;
            canA = str2num(app.TichPhan_CanA.Value);
            canB = str2num(app.TichPhan_CanB.Value);
            selectedOption = app.TichPhan_CachNhapDuLieu.Value;
            X = str2num(app.TichPhan_NhapX.Value);
            Y = str2num(app.TichPhan_NhapY.Value);
            N = app.TichPhan_NhapN.Value;
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if strcmp(selectedOption, 'Hàm số')
                fx = app.TichPhan_NhapHamSo.Value;
                flag = 1; %bật cờ
            elseif strcmp(selectedOption, 'Mảng dữ liệu')
                fx = TichPhan_Lagrange(X, Y);
                fx = char(fx);
                flag = 1; %bật cờ
            end

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.TichPhan_Lamp.Color = 'red';
            if flag == 1 
                    app.TichPhan_Lamp.Color = 'green';
                    
                    if(app.Simpson3phan8.Value)
                        ketqua = double(TichPhan_Simpson3phan8(fx,canA,canB,N));
                        app.TichPhan_KetQua.Text = num2str(ketqua);
                    elseif(app.HinhThang.Value)
                        ketqua = double(TichPhan_HinhThang(fx,canA,canB,N));
                        app.TichPhan_KetQua.Text = num2str(ketqua);
                    elseif(app.Simpson1phan3.Value)
                        ketqua = double(TichPhan_Simpson1phan3(fx,canA,canB,N));
                        app.TichPhan_KetQua.Text = num2str(ketqua);
                    end
            end
            
            clear fx;
        end

        % Value changed function: TichPhan_CachNhapDuLieu
        function TichPhan_CachNhapDuLieuValueChanged(app, event)
            selectedOption = app.TichPhan_CachNhapDuLieu.Value;

            % Kiểm tra giá trị của dropdown và ẩn/hiển thị EditField dựa trên điều kiện
            % Thay 'Option1' bằng giá trị của phần tử bạn muốn kiểm tra
            if strcmp(selectedOption, 'Mảng dữ liệu')
                app.TichPhan_NhapX.Enable = 'on'; % Ẩn EditField
                app.TichPhan_NhapY.Enable = 'on';

                app.TichPhan_NhapHamSo.Enable = "off";
                
            else
                app.TichPhan_NhapX.Enable = 'off'; % Hiển thị EditField
                app.TichPhan_NhapY.Enable = 'off';
                
                app.TichPhan_NhapHamSo.Enable = "on";
            end
        end

        % Button pushed function: ButtonNoisuy
        function ButtonNoisuyPushed(app, event)
            value = app.DropDownNoiSuy.Value;
            if strcmp(value,'Newton')
                xa = str2num(app.EditFieldx.Value);
                ya = str2num(app.EditFieldy.Value);
                x = app.EditFieldgtri.Value;
                L = NewtonSymbolic(xa, ya);
                app.Labeldathuc.Text = "P(x) = " + char(L);
                result = NewtonInterpolation(xa, ya, x);
                app.Labelkq.Text = "Kết quả nội suy Newton = " + num2str(result);
                % Tạo một dãy giá trị x từ min(xa) đến max(xa)
                x_plot = linspace(min(xa), max(xa), 1000);
                
                % Tính giá trị y tương ứng với mỗi giá trị x_plot
                if strcmp(value,'Newton')
                    y_plot = arrayfun(@(x) NewtonInterpolation(xa, ya, x), x_plot);
                end
                
                % Vẽ đồ thị hàm số nội suy trên UIAxes1
                plot(app.UIAxesNoiSuy, x_plot, y_plot);
                
                % Đánh dấu dữ liệu thực trên đồ thị
                hold(app.UIAxesNoiSuy, 'on');
                plot(app.UIAxesNoiSuy, xa, ya, 'ro');
                hold(app.UIAxesNoiSuy, 'off');

            else
                xa = str2num(app.EditFieldx.Value);
                ya = str2num(app.EditFieldy.Value);
                x = app.EditFieldgtri.Value;
                L = LagrangeSymbolic(xa, ya);
                app.Labeldathuc.Text = "P(x) = " + char(L);
                result = Lagrange(xa, ya, x);
                app.Labelkq.Text = "Kết quả nội suy Lagrange = " + num2str(result);

                % Tạo một dãy giá trị x từ min(xa) đến max(xa)
                x_plot = linspace(min(xa), max(xa), 1000);
                
                % Tính giá trị y tương ứng với mỗi giá trị x_plot
                y_plot = arrayfun(@(x) Lagrange(xa, ya, x), x_plot);
                
                % Vẽ đồ thị hàm số nội suy trên UIAxes1
                plot(app.UIAxesNoiSuy, x_plot, y_plot);
                
                % Đánh dấu dữ liệu thực trên đồ thị
                hold(app.UIAxesNoiSuy, 'on');
                plot(app.UIAxesNoiSuy, xa, ya, 'ro');
                hold(app.UIAxesNoiSuy, 'off');
            end
        end

        % Button pushed function: DaoHamButton
        function DaoHamButtonPushed(app, event)
            pp = app.ChonppDaoHam.Value;
            ss = app.GiatrissDropDown.Value;
            mangX = str2num(app.NhpmngxEditField.Value);
            mangY = str2num(app.NhpmngyEditField.Value);
            x = app.Nhapgiatricantinh.Value;
            fx = app.NhphmsEditField.Value;
            h = app.NhapbuochEditField.Value;
            c = find(mangX == x );
            if (app.NhphmsButton.Value == 1)
                if (ss == "O(h^2)")
                    [dx] = tinhdaohamtuhamso(fx,x,h,pp);
                else
                    [dx] = tinhdaohamtuhamso2(fx,x,h,pp);
                end 
            else
                if (ss == "O(h^2)")
                    [dx] = tinhdaohamtumang(mangX,mangY,x,h,pp);
                else
                    [dx] = tinhdaohamtumang2(mangX,mangY,x,h,pp);
                end 
            end
            dx = double(dx);
            if (ss == "O(h)")
                if (pp == "Xấp xỉ trung tâm")
                app.KQLabel.Text = "Không thực hiện được";
                elseif (pp == "Xấp xỉ lùi")
                    if c == 1
                        app.KQLabel.Text = "Error";
                    else
                        app.KQLabel.Text = num2str(dx);
                    end
                else
                    if c == length(mangX)
                        app.KQLabel.Text = "Error";
                    else
                        app.KQLabel.Text = num2str(dx);
                    end
                end
            else
                if (pp == "Xấp xỉ lùi")
                    if c <= 2
                        app.KQLabel.Text = "Error";
                    else
                        app.KQLabel.Text = num2str(dx);
                    end
                else
                    if c > (length(mangX)-2)
                        app.KQLabel.Text = "Error";
                    else
                        app.KQLabel.Text = num2str(dx);
                    end
                end
            end
        end

        % Selection changed function: ChonCachNhapButtonGroup
        function ChonCachNhapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChonCachNhapButtonGroup.SelectedObject;
            if (app.NhphmsButton.Value == 1)
                app.NhpmngxEditField.Visible = "off";
                app.NhpmngxEditFieldLabel.Visible = "off";
                app.NhpmngyEditField.Visible = "off";
                app.NhpmngyEditFieldLabel.Visible = "off";
                app.NhphmsEditField.Visible = "on";
                app.NhphmsEditFieldLabel.Visible ="on";
            else
                app.NhpmngxEditField.Visible = "on";
                app.NhpmngxEditFieldLabel.Visible = "on";
                app.NhpmngyEditField.Visible = "on";
                app.NhpmngyEditFieldLabel.Visible = "on";
                app.NhphmsEditField.Visible = "off";
                app.NhphmsEditFieldLabel.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Nghiem = uitab(app.TabGroup);
            app.Tab_Nghiem.Title = 'Nghiệm';
            app.UIAxesNghiem = uiaxes(app.Tab_Nghiem);
            title(app.UIAxesNghiem, 'Đồ thị')
            xlabel(app.UIAxesNghiem, 'X')
            ylabel(app.UIAxesNghiem, 'Y')
            zlabel(app.UIAxesNghiem, 'Z')
            app.Nghiem_ThcthiButton = uibutton(app.Tab_Nghiem, 'push');
            app.Nghiem_ThcthiButton.ButtonPushedFcn = createCallbackFcn(app, @Nghiem_ThcthiButtonPushed, true);
            app.Nghiem_ThcthiButton.Text = 'THỰC THI';
            app.fxEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.Nghiem_Func = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_Func.Value = 'x^3 - x - 1';
            app.aEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.aEditFieldLabel.Text = 'a: ';
            app.Nghiem_A = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_A.Value = '1';
            app.bEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.bEditFieldLabel.Text = 'b: ';
            app.Nghiem_B = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_B.Value = '2';
            app.epsilonLabel = uilabel(app.Tab_Nghiem);
            app.epsilonLabel.Text = 'epsilon';
            app.Nghiem_epsilon = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_epsilon.Value = '0.01';
            app.KTQULabel = uilabel(app.Tab_Nghiem);
            app.KTQULabel.Text = 'Kết Quả';
            app.Nghiem_KQua = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_KQua.Editable = 'off';
            app.SLnLpLabel = uilabel(app.Tab_Nghiem);
            app.SLnLpLabel.Text = 'Số Lần Lặp';
            app.Nghiem_slLap = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_slLap.Editable = 'off';
            app.gxEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.gxEditFieldLabel.Visible = 'off';
            app.gxEditFieldLabel.Text = 'g(x)';
            app.Nghiem_gxFunc = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_gxFunc.Visible = 'off';
            app.Nghiem_gxFunc.Value = '(x + 1)^(1/3)';
            app.PhngPhpDropDownLabel = uilabel(app.Tab_Nghiem);
            app.PhngPhpDropDownLabel.Text = 'Phương Pháp';
            app.Nghiem_PhuongPhap = uidropdown(app.Tab_Nghiem);
            app.Nghiem_PhuongPhap.Items = {'', 'Chia đôi', 'Lặp', 'Newton'};
            app.Nghiem_PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @Nghiem_PhuongPhapValueChanged, true);
            app.Nghiem_PhuongPhap.Value = '';
            app.Tab_NoiSuy = uitab(app.TabGroup);
            app.Tab_NoiSuy.Title = 'Nội Suy';
            app.UIAxesNoiSuy = uiaxes(app.Tab_NoiSuy);
            title(app.UIAxesNoiSuy, 'Đồ thị Hàm số Nội Suy và Dữ liệu thực')
            xlabel(app.UIAxesNoiSuy, 's = [0:0.1:10]')
            ylabel(app.UIAxesNoiSuy, 'f(s)')
            zlabel(app.UIAxesNoiSuy, 'Z')
            app.xLabel = uilabel(app.Tab_NoiSuy);
            app.xLabel.Text = 'x';
            app.EditFieldx = uieditfield(app.Tab_NoiSuy, 'text');
            app.EditFieldx.Value = '0.6,0.9,1.5,2.4';
            app.yLabel = uilabel(app.Tab_NoiSuy);
            app.yLabel.Text = 'y';
            app.EditFieldy = uieditfield(app.Tab_NoiSuy, 'text');
            app.EditFieldy.Value = '0.09983, 0.19867, 0.29552, 0.38942';
            app.ChnphngphpnisuyLabel = uilabel(app.Tab_NoiSuy);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy:';
            app.DropDownNoiSuy = uidropdown(app.Tab_NoiSuy);
            app.DropDownNoiSuy.Items = {'Newton', 'Lagrange'};
            app.DropDownNoiSuy.Value = 'Newton';
            app.Labeldathuc = uilabel(app.Tab_NoiSuy);
            app.Labeldathuc.Text = 'Biểu thức Nội Suy';
            app.NhpgitrcnnisuyLabel = uilabel(app.Tab_NoiSuy);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.EditFieldgtri = uieditfield(app.Tab_NoiSuy, 'numeric');
            app.EditFieldgtri.Value = 0.14;
            app.Labelkq = uilabel(app.Tab_NoiSuy);
            app.Labelkq.Text = 'Kết quả Nội Suy';
            app.ButtonNoisuy = uibutton(app.Tab_NoiSuy, 'push');
            app.ButtonNoisuy.ButtonPushedFcn = createCallbackFcn(app, @ButtonNoisuyPushed, true);
            app.ButtonNoisuy.Text = 'THỰC THI';
            app.Tab_HoiQuy = uitab(app.TabGroup);
            app.Tab_HoiQuy.Title = 'Hồi Quy';
            app.UIAxesHoiQuy = uiaxes(app.Tab_HoiQuy);
            title(app.UIAxesHoiQuy, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxesHoiQuy, 'X')
            ylabel(app.UIAxesHoiQuy, 'Y')
            zlabel(app.UIAxesHoiQuy, 'Z')
            app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel = uilabel(app.Tab_HoiQuy);
            app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = 'Kết quả phương trình hồi quy';
            app.HoiQuy_KetQuaDuDoanLabel = uilabel(app.Tab_HoiQuy);
            app.HoiQuy_KetQuaDuDoanLabel.Text = 'Kết quả dự đoán';
            app.HoiQuy_TINHButton = uibutton(app.Tab_HoiQuy, 'push');
            app.HoiQuy_TINHButton.ButtonPushedFcn = createCallbackFcn(app, @HoiQuy_TINHButtonPushed, true);
            app.HoiQuy_TINHButton.Text = 'TÍNH';
            app.NhpdliuxEditFieldLabel = uilabel(app.Tab_HoiQuy);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.HoiQuy_NhapDuLieuXEditField = uieditfield(app.Tab_HoiQuy, 'text');
            app.HoiQuy_NhapDuLieuXEditField.Value = '[30, 35, 37, 50, 55]';
            app.NhpdliuyEditFieldLabel = uilabel(app.Tab_HoiQuy);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.HoiQuy_NhapDuLieuYEditField = uieditfield(app.Tab_HoiQuy, 'text');
            app.HoiQuy_NhapDuLieuYEditField.Value = '[500, 550, 570, 700, 750]';
            app.ChnPhngPhpHiQuyDropDownLabel = uilabel(app.Tab_HoiQuy);
            app.ChnPhngPhpHiQuyDropDownLabel.Text = 'Chọn Phương Pháp Hồi Quy';
            app.ChonPhuongPhapHoiQuyDropDown = uidropdown(app.Tab_HoiQuy);
            app.ChonPhuongPhapHoiQuyDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChonPhuongPhapHoiQuyDropDown.Value = 'Tuyến tính';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.Tab_HoiQuy);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhapGiaTriCanDuDoanEditField = uieditfield(app.Tab_HoiQuy, 'text');
            app.NhapGiaTriCanDuDoanEditField.Value = '40';
            app.Tab_DaoHam = uitab(app.TabGroup);
            app.Tab_DaoHam.Title = 'Đạo hàm';
            app.NhpbchEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhapbuochEditField = uieditfield(app.Tab_DaoHam, 'numeric');
            app.NhapbuochEditField.Value = 0.5;
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm ';
            app.Nhapgiatricantinh = uieditfield(app.Tab_DaoHam, 'numeric');
            app.Nhapgiatricantinh.Value = 2;
            app.DaoHamButton = uibutton(app.Tab_DaoHam, 'push');
            app.DaoHamButton.ButtonPushedFcn = createCallbackFcn(app, @DaoHamButtonPushed, true);
            app.DaoHamButton.Text = 'ĐẠO HÀM';
            app.KQLabel = uilabel(app.Tab_DaoHam);
            app.KQLabel.Text = 'Kết quả đạo hàm';
            app.ChnphngphpohmDropDownLabel = uilabel(app.Tab_DaoHam);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChonppDaoHam = uidropdown(app.Tab_DaoHam);
            app.ChonppDaoHam.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChonppDaoHam.Value = 'Xấp xỉ tiến';
            app.NhpmngyEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpmngyEditFieldLabel.Text = 'Nhập mảng y';
            app.NhpmngyEditField = uieditfield(app.Tab_DaoHam, 'text');
            app.NhpmngyEditField.Value = '[4 5 6 7]';
            app.ChngitrsaisDropDownLabel = uilabel(app.Tab_DaoHam);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.GiatrissDropDown = uidropdown(app.Tab_DaoHam);
            app.GiatrissDropDown.Items = {'O(h)', 'O(h^2)'};
            app.GiatrissDropDown.Value = 'O(h)';
            app.ChonCachNhapButtonGroup = uibuttongroup(app.Tab_DaoHam);
            app.ChonCachNhapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChonCachNhapButtonGroupSelectionChanged, true);
            app.ChonCachNhapButtonGroup.Title = '    Chọn cách nhập';
            app.NhpmngxyButton = uiradiobutton(app.ChonCachNhapButtonGroup);
            app.NhpmngxyButton.Text = 'Nhập mảng x,y';
            app.NhpmngxyButton.Value = true;
            app.NhphmsButton = uiradiobutton(app.ChonCachNhapButtonGroup);
            app.NhphmsButton.Text = 'Nhập hàm số';
            app.NhpmngxEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpmngxEditFieldLabel.Text = 'Nhập mảng x';
            app.NhpmngxEditField = uieditfield(app.Tab_DaoHam, 'text');
            app.NhpmngxEditField.Value = '[1 2 3 4]';
            app.NhphmsEditFieldLabel_2 = uilabel(app.Tab_DaoHam);
            app.NhphmsEditFieldLabel_2.Visible = 'off';
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.NhphmsEditField = uieditfield(app.Tab_DaoHam, 'text');
            app.NhphmsEditField.Visible = 'off';
            app.NhphmsEditField.Value = '3.*x.^3 + 4.*x^2 + 3';
            app.KQLabel_2 = uilabel(app.Tab_DaoHam);
            app.KQLabel_2.Text = '                        Kết quả';
            app.Tab_TichPhan = uitab(app.TabGroup);
            app.Tab_TichPhan.Title = 'Tích Phân';
            app.TichPhan_Hoac = uilabel(app.Tab_TichPhan);
            app.TichPhan_Hoac.Text = 'Hoặc';
            app.TichPhan_PhuongPhapTP = uibuttongroup(app.Tab_TichPhan);
            app.TichPhan_PhuongPhapTP.Title = 'Chọn phương pháp Tích phân';
            app.HinhThang = uiradiobutton(app.TichPhan_PhuongPhapTP);
            app.HinhThang.Text = 'Hình thang';
            app.HinhThang.Value = true;
            app.Simpson1phan3 = uiradiobutton(app.TichPhan_PhuongPhapTP);
            app.Simpson1phan3.Text = 'Simpson 1/3';
            app.Simpson3phan8 = uiradiobutton(app.TichPhan_PhuongPhapTP);
            app.Simpson3phan8.Text = 'Simpson 3/8';
            app.TichPhan_Tinh = uibutton(app.Tab_TichPhan, 'push');
            app.TichPhan_Tinh.ButtonPushedFcn = createCallbackFcn(app, @TichPhan_TinhButtonPushed, true);
            app.TichPhan_Tinh.Text = 'TÍNH';
            app.TichPhan_KetQua = uilabel(app.Tab_TichPhan);
            app.TichPhan_KetQua.Text = 'Kết Quả';
            app.NhphmsEditFieldLabel = uilabel(app.Tab_TichPhan);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.TichPhan_NhapHamSo = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_NhapHamSo.Enable = 'off';
            app.TichPhan_NhapHamSo.Value = '1/(1+x^2)';
            app.MngXLabel = uilabel(app.Tab_TichPhan);
            app.MngXLabel.Text = 'Mảng x:';
            app.TichPhan_NhapX = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_NhapX.Value = '1, 2, 3, 4, 5';
            app.MngYLabel = uilabel(app.Tab_TichPhan);
            app.MngYLabel.Text = 'Mảng y(x):';
            app.TichPhan_NhapY = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_NhapY.Value = '1/2, 1/5, 1/10, 1/17, 1/26';
            app.PhthinliLampLabel = uilabel(app.Tab_TichPhan);
            app.PhthinliLampLabel.Text = 'Phát hiện lỗi';
            app.TichPhan_Lamp = uilamp(app.Tab_TichPhan);
            app.CnaEditFieldLabel = uilabel(app.Tab_TichPhan);
            app.CnaEditFieldLabel.Text = 'Cận a:';
            app.TichPhan_CanA = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_CanA.Value = '1';
            app.TichPhan_CanB = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_CanB.Value = '5';
            app.CnbEditFieldLabel = uilabel(app.Tab_TichPhan);
            app.CnbEditFieldLabel.Text = 'Cận b:';
            app.NhpnLabel = uilabel(app.Tab_TichPhan);
            app.NhpnLabel.Text = 'Nhập N (n đoạn):';
            app.TichPhan_NhapN = uieditfield(app.Tab_TichPhan, 'numeric');
            app.TichPhan_NhapN.Value = 4;
            app.CchNhpDropDownLabel = uilabel(app.Tab_TichPhan);
            app.CchNhpDropDownLabel.Text = 'Cách Nhập:';
            app.TichPhan_CachNhapDuLieu = uidropdown(app.Tab_TichPhan);
            app.TichPhan_CachNhapDuLieu.Items = {'Mảng dữ liệu', 'Hàm số'};
            app.TichPhan_CachNhapDuLieu.ValueChangedFcn = createCallbackFcn(app, @TichPhan_CachNhapDuLieuValueChanged, true);
            app.TichPhan_CachNhapDuLieu.Value = 'Mảng dữ liệu';
            app.Tab_GioiThieuNhom = uitab(app.TabGroup);
            app.Tab_GioiThieuNhom.Title = 'Giới thiệu nhóm';
            app.Image = uiimage(app.Tab_GioiThieuNhom);
            app.Image.ImageSource = 'logoFETEL.jpg';
            app.NCUIKPHNGPHPTNHVMATLABLabel = uilabel(app.Tab_GioiThieuNhom);
            app.NCUIKPHNGPHPTNHVMATLABLabel.Text = {'ĐỒ ÁN CUỐI KỲ'; 'PHƯƠNG PHÁP TÍNH VÀ MATLAB'};
            app.NHMSINHVINTHCHINNHM6BROLabel = uilabel(app.Tab_GioiThieuNhom);
            app.NHMSINHVINTHCHINNHM6BROLabel.Text = 'NHÓM SINH VIÊN THỰC HIỆN: NHÓM 6BRO';
            app.GINGVINHNGDNHUNHQUCTHNHLabel = uilabel(app.Tab_GioiThieuNhom);
            app.GINGVINHNGDNHUNHQUCTHNHLabel.Text = 'GIẢNG VIÊN HƯỚNG DẪN: HUỲNH QUỐC THỊNH';
            app.DANHCHHINNhmtrng21200287Label = uilabel(app.Tab_GioiThieuNhom);
            app.DANHCHHINNhmtrng21200287Label.Text = 'DANH CHÍ HIỀN (Nhóm trưởng) - 21200287';
            app.NGUYNGIANGThk21200284Label = uilabel(app.Tab_GioiThieuNhom);
            app.NGUYNGIANGThk21200284Label.Text = 'NGUYỄN GIANG (Thư ký) - 21200284';
            app.TRNNGCHUY20200040Label = uilabel(app.Tab_GioiThieuNhom);
            app.TRNNGCHUY20200040Label.Text = 'TRẦN NGỌC HUY - 20200040';
            app.TRNHUHNH21200286Label = uilabel(app.Tab_GioiThieuNhom);
            app.TRNHUHNH21200286Label.Text = 'TRẦN HỮU HẠNH - 21200286';
            app.NGUYNTHAVHIP21200288Label = uilabel(app.Tab_GioiThieuNhom);
            app.NGUYNTHAVHIP21200288Label.Text = 'NGUYỄN THỪA VŨ HIỆP - 21200288';
            app.TRNLNHTQUIN21200342Label = uilabel(app.Tab_GioiThieuNhom);
            app.TRNLNHTQUIN21200342Label.Text = 'TRẦN LÊ NHẬT QUIN - 21200342';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6BRO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end